classdef Start_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Tab4_EnableCheckBox           matlab.ui.control.CheckBox
        Tab3_EnableCheckBox           matlab.ui.control.CheckBox
        Tab2_EnableCheckBox           matlab.ui.control.CheckBox
        Tab1_EnableCheckBox           matlab.ui.control.CheckBox
        TabGroup                      matlab.ui.container.TabGroup
        WellcomeTab                   matlab.ui.container.Tab
        LetsgoButton                  matlab.ui.control.Button
        UsageTextArea_6               matlab.ui.control.TextArea
        UsageTextArea_5               matlab.ui.control.TextArea
        UsageTextArea_4               matlab.ui.control.TextArea
        UsageTextArea_3               matlab.ui.control.TextArea
        TextArea                      matlab.ui.control.TextArea
        UsageTextArea_2               matlab.ui.control.TextArea
        CheckOurGithubRepoLabel       matlab.ui.control.Label
        Image3_2                      matlab.ui.control.Image
        Image_5                       matlab.ui.control.Image
        UsageTextArea                 matlab.ui.control.TextArea
        CheckOurProjectPageLabel      matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Step1DataSelectionTab         matlab.ui.container.Tab
        FolderTreeLabel               matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        ImageSizeTextArea             matlab.ui.control.TextArea
        ImageSizeTextAreaLabel        matlab.ui.control.Label
        SelectedDataLabel             matlab.ui.control.Label
        ImageNameTextArea             matlab.ui.control.TextArea
        ImageNameTextAreaLabel        matlab.ui.control.Label
        SpecifyDatasetRootButton      matlab.ui.control.Button
        Tree                          matlab.ui.container.Tree
        NextStepButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Step2ForegroundSelectionTab   matlab.ui.container.Tab
        ForegroundMaskLabel           matlab.ui.control.Label
        InpaintingBackgroundLabel     matlab.ui.control.Label
        FGResetButton                 matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        SelectanewimageButton         matlab.ui.control.Button
        IftheresnoforegroundjustclickNextStepLabel  matlab.ui.control.Label
        NextStepButton_2              matlab.ui.control.Button
        LampForFG                     matlab.ui.control.Lamp
        LabelForegroundButton         matlab.ui.control.Button
        UIAxesBG                      matlab.ui.control.UIAxes
        UIAxesFG                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        Step3SpideryMeshTab           matlab.ui.container.Tab
        Image_3                       matlab.ui.control.Image
        SelectanewimageButton_2       matlab.ui.control.Button
        NextStepButton_3              matlab.ui.control.Button
        ClickthepointtoconfirmButton  matlab.ui.control.StateButton
        LampForRW                     matlab.ui.control.Lamp
        LampForVP                     matlab.ui.control.Lamp
        SelectarectangletoconfirmButton  matlab.ui.control.StateButton
        SelectwallButton              matlab.ui.control.Button
        SelectVanishingpointButton    matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        TimetoTourTab                 matlab.ui.container.Tab
        QuitButton                    matlab.ui.control.Button
        AdjustCameraPoseLabel         matlab.ui.control.Label
        AdjustCameraAngleLabel        matlab.ui.control.Label
        SaveCurrentViewButton         matlab.ui.control.Button
        Image_4                       matlab.ui.control.Image
        SelectanewimageButton_3       matlab.ui.control.Button
        ShowForegroundButton          matlab.ui.control.StateButton
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        ResetCameraPoseButton         matlab.ui.control.Button
        phiSlider                     matlab.ui.control.Slider
        phiSliderLabel                matlab.ui.control.Label
        thetaSlider                   matlab.ui.control.Slider
        thetaSliderLabel              matlab.ui.control.Label
        ZSlider                       matlab.ui.control.Slider
        ZSliderLabel                  matlab.ui.control.Label
        UIAxes_4                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % global params
        DEBUG_MODE = false;                          % if true, run debug mode

        % params of step1
        default_image_names = {'metro-station', 'oil-painting', ...
            'sagrada_familia', 'shopping-mall', 'simple-room', 'uhren-turm'};
        image_path                                  % Path of images
        image_names                                 % The name list of the select dataset
        selected_image;                             % Path of the selected image
        selected_image_name;                        % The name of the selected image
        selected_image_data;                        % The data of selected image (unit8)

        % params of step2
        checkFG = false;                            % <GUI> Check whether the foreground is successfully inputed.

        image_BG;                                   % The selected image without foreground (double)
        image_FG;                                   % The foreground of selected image (double)
                                                    % The composited image =
                                                    % image_BG.*BG_mask + image_FG

        FG_raw = zeros(0,2);                        % 4 vertices of foreground (without border) ([upper left; upper right; lower left; lower right])
        FG;                                         % Image only include foreground.
        FG_3d;                                      % 4 vertices of foreground (homogenous coordinates)
        BG_mask;                                    % black white image, to create a foreground region in background.
        FG_points_2d;                               % foreground coordinates after camera movement.
        showforeground;                             % check the relative position of foreground and camera.

        % params of step3   
        checkVP = false;                            % <GUI> Check whether the vanishing point is successfully inputed.
        checkRW = false;                            % <GUI> Check whether the rear wall is successfully inputed.

        lmargin;                                    % Border size (left)
        rmargin;                                    % Border size (right)
        tmargin;                                    % Border size (top)
        bmargin;                                    % Border size (bottom)

        rearwall_raw;                               % the four vertex of the rearwall without border
        rearwall;                                   % the four vertex of the rearwall with border
        VP_raw;                                     % The coordinate of selected vanishing point (without border)
        VP;                                         % The coordinate of selected vanishing point (with border)
              
        x_limit;                                    % The pixel limit in horizontal direction
        y_limit;                                    % The pixel limit in vertical direction
        
        points_2d;                                  % 13-point coordinates of 2d
        points_3d;                                  % 13-point coordinates of 3d

        select_point;                               % <GUI> Selected vanishing point by user. (Data type: Point)
        select_rectange;                            % <GUI> Selected rectangle by user. (Data type: rectangle)
        draw_point;                                 % <GUI> The drawed point on the UIAxes_2, can be deleted by delete(.).
        draw_rectangle;                             % <GUI> The drawed rectangle on the UIAxes_2, can be deleted by delete(.);
        
        rx                                          % <GUI>
        ry                                          % <GUI>
        irx                                         % <GUI>
        iry                                         % <GUI>

        bim;                                        % big image(image with border)
    
        % params of step4
        phi;                                        % phi: pitch in rad
        theta;                                      % theta: yaw in rad
        x;                                          % x: displacement in x axis
        y;                                          % y: displacement in y axis
        z;                                          % z: displacement in z axis
        K_trans;                                    % Camera matrix after transformation in dimension 3x4
        CameraMatrix;                               % Camera matrix of dimension 3x4

        trans_points_2d;                            % Devide the third component of homogenous coordinates
        trans_points_3d;                            % Homogeneous coordinates of vertices after transformation

        RearWall;                                   % Splited trapezoid of rear wall
        LeftWall;                                   % Splited trapezoid of left wall
        RightWall;                                  % Splited trapezoid of right wall
        Ceil;                                       % Splited trapezoid of ceiling
        Floor;                                      % Splited trapezoid of floor

        movedRearWall;                              % rear wall after camera movement.      
        movedLeftWall;                              % left wall after camera movement.  
        movedRightWall;                             % right wall after camera movement.  
        movedCeil;                                  % ceiling after camera movement.  
        movedFloor;                                 % floor after camera movement.  


        save_img;

        FG_count = 0;


    end
      
    
    methods (Access = private)
        
        function drawLines(app)
            % draw lines based on the app.VP and the app.rearwall
            app.NextStepButton_3.Visible = 'on';       
            app.NextStepButton_3.Enable = 'on';
            
            % assign the value
            vx = app.VP_raw(1);
            vy = app.VP_raw(2);

            % find where the line from VP thru inner rectangle hits the edge of image
            [ox,oy] = find_corner(vx,vy,app.irx(1),app.iry(1),0,0);
            orx(1) = ox;  ory(1) = oy;
            [ox,oy] = find_corner(vx,vy,app.irx(2),app.iry(2),app.x_limit,0);
            orx(2) = ox;  ory(2) = oy;
            [ox,oy] = find_corner(vx,vy,app.irx(3),app.iry(3),app.x_limit,app.y_limit);
            orx(3) = ox;  ory(3) = oy;
            [ox,oy] = find_corner(vx,vy,app.irx(4),app.iry(4),0,app.y_limit);
            orx(4) = ox;  ory(4) = oy;
            orx = round(orx);
            ory = round(ory);
            
            % draw everything
            app.irx = round([app.rx(1) app.rx(2) app.rx(2) app.rx(1) app.rx(1)]);
            app.iry =  round([app.ry(1) app.ry(1) app.ry(2) app.ry(2) app.ry(1)]);    
            plot(app.irx,app.iry,'b','Parent', app.UIAxes_3); 
            hold(app.UIAxes_2, 'on' );
            plot([vx app.irx(1)], [vy app.iry(1)], 'r-.','Parent', app.UIAxes_3);
            plot([orx(1) app.irx(1)], [ory(1) app.iry(1)], 'r','Parent', app.UIAxes_3);
            plot([vx app.irx(2)], [vy app.iry(2)], 'r-.','Parent', app.UIAxes_3);
            plot([orx(2) app.irx(2)], [ory(2) app.iry(2)], 'r','Parent', app.UIAxes_3);
            plot([vx app.irx(3)], [vy app.iry(3)], 'r-.','Parent', app.UIAxes_3);
            plot([orx(3) app.irx(3)], [ory(3) app.iry(3)], 'r','Parent', app.UIAxes_3);
            plot([vx app.irx(4)], [vy app.iry(4)], 'r-.','Parent', app.UIAxes_3);
            plot([orx(4) app.irx(4)], [ory(4) app.iry(4)], 'r','Parent', app.UIAxes_3);
            hold(app.UIAxes_3, 'off' );
        end
        
        function step3Processes(app)
            % get 13 points in 2d and 3d
            get2DPoints(app);
            get3DPoints(app);
            % <GUI> draw the lines
            drawLines(app);
            % calculate the 5 rectangle
            calFiveRect(app);
            if app.checkFG == true
                foreground_3D(app);
                % add border to foreground
                app.image_FG = addBorder(app, app.image_FG);
                app.BG_mask = addBorder(app, app.BG_mask);
            end
        end

        function calFiveRect(app)
            % loop for assign the values
            for k = 1:12
                eval(['p',num2str(k),'= app.points_2d(k, :);']);
            end
            
            % get five rectangles
            app.RearWall = splitTrapezoid (app.bim, p7, p8, p2, p1); 
            try
                app.Ceil = splitTrapezoid (app.bim, p9, p10, p8, p7);
            catch
                app.Ceil = zeros(size(app.bim));
            end

            try
                app.Floor = splitTrapezoid (app.bim, p1, p2, p4, p3);
            catch
                app.Floor = zeros(size(app.bim));
            end

            try
                app.RightWall = splitTrapezoid (app.bim, p8, p12, p6, p2);
            catch
                app.RightWall = zeros(size(app.bim));
            end

            try
                app.LeftWall = splitTrapezoid (app.bim, p11, p7, p1, p5);
            catch
                app.LeftWall = zeros(size(app.bim));
            end
        end


        function getCompositeImage(app)

            for k = 1:12
                eval(['p',num2str(k),'= app.points_2d(k, :);']);
            end
            
            % get the slider values
            app.x = app.XSlider.Value;
            app.y = app.YSlider.Value;
            app.z = app.ZSlider.Value;
            app.theta = app.thetaSlider.Value;
            app.phi = app.phiSlider.Value;
         
            % get the pixel coordinates who are currently at image plane.
            % transRectVerticesCoord_pix_judge: judge whether this wall is
            % blocked

            getTransCoordinates2D(app);
            [transRectVerticesCoord_pix, transRectVerticesCoord_pix_judge] = transRectVertices(app);

            pix_x = round(transRectVerticesCoord_pix(:,1));
            pix_y = round(transRectVerticesCoord_pix(:,2));

            pix_x(pix_x<0) = 0;
            pix_y(pix_y<0) = 0;
            pix_x(pix_x>size(app.bim,2)) = size(app.bim,2);
            pix_y(pix_y>size(app.bim,1)) = size(app.bim,1);
            

            pix_x_judge = round(transRectVerticesCoord_pix_judge(:,1));
            pix_y_judge = round(transRectVerticesCoord_pix_judge(:,2));

            pix = [pix_x, pix_y];
            pix_judge = [pix_x_judge, pix_y_judge];

            for k = 1:12
                eval(['p_pix',num2str(k),'= pix(k, :);']);
                eval(['p_judge',num2str(k),'= pix_judge(k, :);']);
                eval(['transP',num2str(k),'_2= round(app.trans_points_2d(k, :));']);
            end        
      
            % split the trapezoid that can show in the transformed picture
            RearWall_temp = app.RearWall; 
            Ceil_temp = splitTrapezoid (app.Ceil, p_pix9, p_pix10, p8, p7); 
            Floor_temp = splitTrapezoid (app.Floor, p1, p2, p_pix4, p_pix3); 
            RightWall_temp = splitTrapezoid (app.RightWall, p8, p_pix12, p_pix6, p2); 
            LeftWall_temp = splitTrapezoid (app.LeftWall, p_pix11, p7, p1, p_pix5);

            
            % strech 5 wall to transformed form.
            app.movedRearWall = getNewTrapezoid ('f', RearWall_temp, p7, p8, p2, p1, transP7_2, transP8_2, transP2_2, transP1_2, ...
                                                    p_judge7,p_judge8, p_judge2, p_judge1);
            app.movedCeil = getNewTrapezoid ('fc', Ceil_temp, p9, p10, p8, p7, transP9_2, transP10_2, transP8_2, transP7_2, ...
                                                    p_judge9,p_judge10, p_judge8, p_judge7);
            app.movedFloor = getNewTrapezoid ('fc', Floor_temp, p1, p2, p4, p3, transP1_2, transP2_2, transP4_2, transP3_2, ...
                                                    p_judge1,p_judge2, p_judge4, p_judge3);
            app.movedRightWall = getNewTrapezoid ('lr', RightWall_temp, p8, p12, p6, p2, transP8_2, transP12_2, transP6_2, transP2_2, ...
                                                    p_judge8, p_judge12, p_judge6, p_judge2);
            app.movedLeftWall = getNewTrapezoid ('lr', LeftWall_temp, p11, p7, p1, p5, transP11_2, transP7_2, transP1_2, transP5_2, ...
                                                    p_judge11,p_judge7, p_judge1,p_judge5);

            % composite image
            newImage = app.movedRearWall+app.movedCeil+app.movedFloor+app.movedRightWall+app.movedLeftWall;
            
            % check whether a foregorund (foregrounds) is (are) selected.
            if app.ShowForegroundButton.Value                    

                movedBG_mask = zeros(size(app.BG_mask));
                movedimage_FG = zeros(size(app.image_FG));

                for i = 1:app.FG_count
                    FG_points = getForegroundPoints2D(app, app.FG_3d(i*4-3:4*i,:));
                    if app.showforeground                
                        BG_mask_temp = zeros(size(app.BG_mask));
                        image_FG_temp = zeros(size(app.image_FG));
                        
                        BG_mask_temp(app.FG(4*i-3,2):app.FG(4*i,2), app.FG(4*i-3,1):app.FG(4*i,1), :) = app.BG_mask(app.FG(4*i-3,2):app.FG(4*i,2), app.FG(4*i-3,1):app.FG(4*i,1), :);
                        image_FG_temp(app.FG(4*i-3,2):app.FG(4*i,2), app.FG(4*i-3,1):app.FG(4*i,1), :) = app.image_FG(app.FG(4*i-3,2):app.FG(4*i,2), app.FG(4*i-3,1):app.FG(4*i,1), :);

                        movedBG_mask_temp = getNewTrapezoid ('f', BG_mask_temp, app.FG(4*i-3,:), app.FG(4*i-2,:), app.FG(4*i-1,:), app.FG(4*i,:), FG_points(1,:), ...
                                                        FG_points(2,:), FG_points(3,:), FG_points(4,:));
                        movedimage_FG_temp = getNewTrapezoid ('f', image_FG_temp, app.FG(4*i-3,:), app.FG(4*i-2,:), app.FG(4*i-1,:), app.FG(4*i,:), FG_points(1,:), ...
                                                         FG_points(2,:), FG_points(3,:), FG_points(4,:));

                       
                        movedBG_mask = movedBG_mask + movedBG_mask_temp;
                        movedimage_FG = movedimage_FG + movedimage_FG_temp;
                    else 
                        continue;
                    end

                end      
                movedBG_mask = abs(movedBG_mask-1);
                newImage = movedBG_mask.*newImage + movedimage_FG;

            end

            newImage = newImage(app.tmargin+1:size(newImage,1)-app.bmargin, app.lmargin+1:size(newImage,2)-app.rmargin, : );
            imshow(newImage, 'Parent', app.UIAxes_4);

            app.save_img = newImage;

        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; 
            clear;

            addpath(genpath('functions'))
            % lock the interaction with difference highlight
            app.UIAxes.Interactions = [];

            % to make sure that button only visible after selecting image
            app.NextStepButton.Visible = "off";
            app.NextStepButton.Enable = "off";
            app.Step3SpideryMeshTab.HandleVisibility = "off";
        end

        % Button pushed function: SpecifyDatasetRootButton
        function SpecifyDatasetRootButtonPushed(app, event)
            try
                GetDatasetList(app)
            catch
                startupFcn(app);
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if ~isempty(selectedNodes.NodeData)
                % save the selected dataset path
                app.selected_image = selectedNodes.NodeData;
                app.selected_image_data = imread(selectedNodes.NodeData);
                % get the dataset 
                % get the dataset name
                [~,dirname,~] = fileparts(selectedNodes.NodeData);
                app.selected_image_name = dirname;
                app.ImageNameTextArea.Value = app.selected_image_name;
                img_size = size(app.selected_image_data);
                app.ImageSizeTextArea.Value = string(img_size(1)) + "*"...
                                            + string(img_size(2)) ;
                
                % calculatie the resolution of img
                img_reso = img_size(1) * img_size(2);
                if img_reso > 2e6
                    resize_ratio = 2e6 / img_reso;
                    app.selected_image_data = imresize(app.selected_image_data, ...
                        resize_ratio,'nearest');
                end

                app.image_BG = im2double(app.selected_image_data);
                app.BG_mask = zeros(size(app.image_BG));
                app.image_FG = zeros(size(app.image_BG));

                % read the number of pixels of selected picture.
                [app.y_limit,app.x_limit,~] = size(app.selected_image_data);

                % show the selected image on axes.
                imshow(app.selected_image_data, 'Parent', app.UIAxes);hold(app.UIAxes, 'on' );
                imshow(app.selected_image_data, 'Parent', app.UIAxes_2);
                app.Image2.Visible = 'off';
                app.UIAxes.Visible = "on";
                
                % set the maximum value of x-axe and y-axe. 
                app.UIAxes.YLim = [0 app.y_limit];
                app.UIAxes.XLim = [0 app.x_limit];

                app.UIAxes_3.YLim = [0 app.y_limit];
                app.UIAxes_3.XLim = [0 app.x_limit];

                app.UIAxes_2.YLim = [0 app.y_limit];
                app.UIAxes_2.XLim = [0 app.x_limit];

                % show the next setp button
                app.NextStepButton.Visible = "on";
                app.NextStepButton.Enable = "on";
                app.Step3SpideryMeshTab.HandleVisibility = "on";

            else
                
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox
            % Each "case" is the title of a Tab and contains the pair checkbox.
            switch selectedTab.Title
                case 'Step 1'
                    checkboxValue = app.Tab1_EnableCheckBox.Value;
                case 'Step 2'
                    checkboxValue = app.Tab2_EnableCheckBox.Value;
                case 'Step 3'
                    checkboxValue = app.Tab3_EnableCheckBox.Value;
                case 'Tour'
                    checkboxValue = app.Tab4_EnableCheckBox.Value;
                otherwise
                    error('Unknown tab: %s.',selectedTab.Title)
            end

            % If paired checkbox is checked, return to the previously selected tab
            if ~checkboxValue
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Button pushed function: FGResetButton
        function FGResetButtonPushed(app, event)
            app.image_BG = im2double(app.selected_image_data);
            imshow(app.image_BG, 'Parent', app.UIAxes_2);
            app.LampForFG.Color = 'r';
            app.checkFG = false;
            app.FG_raw = zeros(0,2);
            app.BG_mask = zeros(size(app.image_BG));
            app.image_FG = zeros(size(app.image_BG));
            app.LabelForegroundButton.Text = 'Select Foreground';
            app.FG_count = 0;

            imshow(app.image_BG, 'Parent', app.UIAxesBG);
            app.UIAxesBG.YLim = [0 app.y_limit];
            app.UIAxesBG.XLim = [0 app.x_limit];
            imshow(app.image_FG, 'Parent', app.UIAxesFG);
            app.UIAxesFG.YLim = [0 app.y_limit];
            app.UIAxesFG.XLim = [0 app.x_limit];

        end

        % Button pushed function: LabelForegroundButton
        function LabelForegroundButtonPushed(app, event)
            % select foreground

            selected_FG = drawpolygon(app.UIAxes_2);
            hold(app.UIAxes_2, 'on');
            selected_FG.Visible = 'off';
            FG_r = selected_FG.Position;
            plot([FG_r(:,1);FG_r(1,1)],[FG_r(:,2);FG_r(1,2)],'r','Parent', app.UIAxes_2);
            hold(app.UIAxes_2, 'on');
            
            roi = poly2mask(FG_r(:,1),FG_r(:,2),app.y_limit,app.x_limit);
            L = superpixels(app.image_BG,500);
            FG_mask_temp = double(grabcut(app.image_BG, L, roi));
            img_FG = FG_mask_temp.*app.image_BG;

            % this is for more foregrounds.
            app.image_FG = app.image_FG+img_FG;
            imshow(app.image_FG, 'Parent', app.UIAxesFG);

            % get the coordinates of foreground
            xmin = round(min(FG_r(:,1)));
            xmax = round(max(FG_r(:,1)));
            ymin = round(min(FG_r(:,2)));
            ymax = round(max(FG_r(:,2)));

            FG_r = [xmin, ymin;
                    xmax, ymin;
                    xmin, ymax;
                    xmax, ymax];

            % get the background mask.
            BGmask = [];
            BGmask(:,:,1) = FG_mask_temp;
            BGmask(:,:,2) = FG_mask_temp;
            BGmask(:,:,3) = FG_mask_temp;

            % background fill
            
            app.image_BG = inpaintExemplar(app.image_BG, roi);
            imshow(app.image_BG, 'Parent', app.UIAxesBG);

            app.LampForFG.Color = 'g';
            app.checkFG = true;

            imshow(app.image_BG, 'Parent', app.UIAxes_3);

            app.LabelForegroundButton.Text = 'Add more foreground';
            app.FG_count = app.FG_count+1;
            app.FG_raw = [app.FG_raw; FG_r];
            app.BG_mask = app.BG_mask + BGmask;

        end

        % Button pushed function: SelectVanishingpointButton
        function SelectVanishingpointButtonPushed(app, event)
            app.checkVP = false;
            app.LampForVP.Color = 'r';
            app.NextStepButton_3.Visible = 'off';        
            app.NextStepButton_3.Enable = 'off';    
            if app.checkVP ~= app.checkRW
                imshow(app.image_BG, 'Parent', app.UIAxes_3);
                hold(app.UIAxes_3, 'on' );
                app.draw_rectangle = plot(app.irx,app.iry,'b','Parent', app.UIAxes_3); 
            end

            app.SelectVanishingpointButton.Visible = 'off';
            app.SelectVanishingpointButton.Enable = 'off';
            
            app.ClickthepointtoconfirmButton.Visible = 'on';
            app.ClickthepointtoconfirmButton.Enable = 'on';
            
            delete(app.select_point);
            delete(app.draw_point);
            app.select_point = drawpoint(app.UIAxes_3);
            app.select_point.Visible = 'off';
            % get the coordinate of selected point.
            app.VP_raw = round(app.select_point.Position);
            app.draw_point = plot(app.UIAxes_3, app.VP_raw(1),app.VP_raw(2), ...
                'Marker','o','MarkerSize',8,'MarkerFaceColor','m'); 
            app.VP = app.VP_raw;

            app.ClickthepointtoconfirmButton.Visible = 'off';
            app.ClickthepointtoconfirmButton.Enable = 'off';
            app.SelectVanishingpointButton.Visible = 'on';
            app.SelectVanishingpointButton.Enable = 'on';
            app.LampForVP.Color = 'g';

            % set the sign
            app.checkVP = true;

            % check if the other button has been selected 
            if app.checkVP == app.checkRW
                step3Processes(app);
            end
        end

        % Button pushed function: SelectwallButton
        function SelectwallButtonPushed(app, event)
            app.NextStepButton_3.Visible = 'off';        
            app.NextStepButton_3.Enable = 'off';  
            app.checkRW = false;
            app.LampForRW.Color = 'r';
            if app.checkVP ~= app.checkRW
                imshow(app.image_BG, 'Parent', app.UIAxes_3);
                hold(app.UIAxes_3, 'on' );
                app.draw_point = plot(app.UIAxes_3, ...
                            app.VP_raw(1), ...
                            app.VP_raw(2), ...
                            'Marker','o','MarkerSize',8, ...
                            'MarkerFaceColor','m'); 
            end

            app.SelectwallButton.Visible = 'off';
            app.SelectwallButton.Enable = 'off';
            app.SelectarectangletoconfirmButton.Visible = 'on';
            app.SelectarectangletoconfirmButton.Enable = 'on';
            
            delete(app.draw_rectangle);
            delete(app.select_rectange);
            app.select_rectange = drawrectangle(app.UIAxes_3);
            app.select_rectange.Visible = 'off';
            
            positionRW = app.select_rectange.Position;
            xmin = positionRW(1);
            ymin = positionRW(2);
            width = positionRW(3);
            height = positionRW(4);
            
            % [p1; p2; p7; p8]
            app.rearwall_raw = round([    xmin, ymin+height;
                                    xmin+width, ymin+height; 
                                          xmin,        ymin;
                                    xmin+width,        ymin;]);

            % update the value to the global params
            app.rx = [xmin, xmin+width];
            app.ry = [ymin, ymin+height];
            app.irx = round([app.rx(1) app.rx(2) app.rx(2) app.rx(1) app.rx(1)]);
            app.iry = round([app.ry(1) app.ry(1) app.ry(2) app.ry(2) app.ry(1)]);
            app.draw_rectangle = plot(app.irx,app.iry,'b','Parent', app.UIAxes_3); 
            
            app.SelectwallButton.Visible = 'on';
            app.SelectwallButton.Enable = 'on';
            app.SelectarectangletoconfirmButton.Visible = 'off';
            app.SelectarectangletoconfirmButton.Enable = 'off';
            app.LampForRW.Color = 'g';  

            % set the sign
            app.checkRW = true;

            % check if the other button has been selected 
            if app.checkVP == app.checkRW
                step3Processes(app);
            end
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            % Step 1 -> Step 2
            app.TabGroup.SelectedTab = app.Step2ForegroundSelectionTab;
            app.Tab2_EnableCheckBox.Value = true;
 
            app.image_BG = im2double(app.selected_image_data);
            app.image_FG = zeros(size(app.image_BG,1),size(app.image_BG,2),3);
            imshow(app.image_BG, 'Parent', app.UIAxes_3);hold(app.UIAxes_3, 'on' );
            imshow(app.image_FG, 'Parent', app.UIAxesFG);
            app.UIAxesFG.YLim = [0 app.y_limit];
            app.UIAxesFG.XLim = [0 app.x_limit];
            imshow(app.image_BG, 'Parent', app.UIAxesBG);
            app.UIAxesBG.YLim = [0 app.y_limit];
            app.UIAxesBG.XLim = [0 app.x_limit];
        end

        % Button pushed function: NextStepButton_2
        function NextStepButton_2Pushed(app, event)
            % Step 2 -> Step 3
            app.TabGroup.SelectedTab = app.Step3SpideryMeshTab;
            app.Tab3_EnableCheckBox.Value = true;
        end

        % Button pushed function: NextStepButton_3
        function NextStepButton_3Pushed(app, event)
            % Step 3 -> Step 4
            app.TabGroup.SelectedTab = app.TimetoTourTab;
            app.Tab4_EnableCheckBox.Value = true;
            newImage = app.RearWall+app.Ceil+app.Floor+app.RightWall+app.LeftWall;
            newImage = newImage(app.tmargin+1:size(newImage,1)-app.bmargin, app.lmargin+1:size(newImage,2)-app.rmargin, : );
            imshow(newImage, 'Parent', app.UIAxes_4); 
            
            app.save_img = newImage;
            app.ZSlider.Limits =[-round(abs(app.points_3d(13, 3))), round(abs(app.points_3d(13, 3)))];
            if app.checkFG == true
                app.ShowForegroundButton.Visible = 'on';
                app.ShowForegroundButton.Enable = "on";
            end
        end

        % Button pushed function: ResetCameraPoseButton
        function ResetCameraPoseButtonPushed(app, event)
            app.ZSlider.Value = 0;
            app.thetaSlider.Value = 0;
            app.phiSlider.Value = 0;
            app.XSlider.Value = 0;
            app.YSlider.Value = 0;
            try
                getCompositeImage(app);
            catch
                imshow(app.bim(app.tmargin+1:size(app.bim,1)-app.bmargin, app.lmargin+1:size(app.bim,2)-app.rmargin, : ), 'Parent', app.UIAxes_4);
            end
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            try
                getCompositeImage(app);
            catch
                imshow(app.save_img, 'Parent', app.UIAxes_4);
            end
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            try
                getCompositeImage(app);
            catch
                imshow(app.save_img, 'Parent', app.UIAxes_4);
            end
        end

        % Value changed function: thetaSlider
        function thetaSliderValueChanged(app, event)
            try
                getCompositeImage(app);
            catch
                imshow(app.save_img, 'Parent', app.UIAxes_4);
            end
        end

        % Value changed function: phiSlider
        function phiSliderValueChanged(app, event)
            try
                getCompositeImage(app);
            catch
                imshow(app.save_img, 'Parent', app.UIAxes_4);
            end
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            try
                getCompositeImage(app);
            catch
                imshow(app.save_img, 'Parent', app.UIAxes_4);
            end
        end

        % Value changed function: ShowForegroundButton
        function ShowForegroundButtonValueChanged(app, event)
            value = app.ShowForegroundButton.Value;
            try
                getCompositeImage(app);
            catch
                imshow(app.save_img, 'Parent', app.UIAxes_4);
            end
  
            if value == 1 
                app.ShowForegroundButton.Text = 'Hide Foreground';
            else
                app.ShowForegroundButton.Text = 'Show Foreground';
            end
        end

        % Button pushed function: SelectanewimageButton, 
        % ...and 2 other components
        function RESETALLButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Step1DataSelectionTab;
               
            app.checkFG = false;                                                                      
            app.checkVP = false;                            
            app.checkRW = false;

            app.LampForFG.Color = 'r';
            app.LampForVP.Color = 'r';
            app.LampForRW.Color = 'r';

            delete(app.select_point);                            
            delete(app.select_rectange);                          
            delete(app.draw_point);                              
            delete(app.draw_rectangle);               
            
        
            app.ZSlider.Value = 0;
            app.thetaSlider.Value = 0;
            app.phiSlider.Value = 0;
            app.XSlider.Value = 0;
            app.YSlider.Value = 0;

            app.Tab1_EnableCheckBox.Value = false;
            app.Tab2_EnableCheckBox.Value = false;
            app.Tab3_EnableCheckBox.Value = false;
            app.Tab4_EnableCheckBox.Value = false;

            app.NextStepButton.Visible = "off";
            app.NextStepButton.Enable = "off";
            app.NextStepButton_3.Visible = 'off';       
            app.NextStepButton_3.Enable = 'off';
            app.ShowForegroundButton.Visible = 'off';
            app.ShowForegroundButton.Enable = 'off';


            app.ShowForegroundButton.Value = 0;
            app.ShowForegroundButton.Text = 'Show Foreground';

            app.FG_raw = zeros(0,2);
            app.FG = [];
            app.BG_mask = [];
            app.image_FG = [];
            app.LabelForegroundButton.Text = 'Select Foreground';
            app.FG_count = 0;

            
            startupFcn(app);

        end

        % Button pushed function: SaveCurrentViewButton
        function SaveCurrentViewButtonPushed(app, event)
            imwrite(app.save_img,'./result/myResult.png');
            msgbox('Current Image is successfully saved in ./results/myResult.png','Success');
        end

        % Button pushed function: LetsgoButton
        function LetsgoButtonPushed(app, event)
            % wellcome -> Step 1
            app.TabGroup.SelectedTab = app.Step1DataSelectionTab;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.WellcomeTab = uitab(app.TabGroup);
            app.WellcomeTab.Title = 'Wellcome';
            app.Image4 = uiimage(app.WellcomeTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'misc', 'cover_origin.png');
            app.Image3 = uiimage(app.WellcomeTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'misc', 'qrcode_project.png');
            app.CheckOurProjectPageLabel = uilabel(app.WellcomeTab);
            app.CheckOurProjectPageLabel.Text = 'Check Our Project Page';
            app.UsageTextArea = uitextarea(app.WellcomeTab);
            app.UsageTextArea.Value = {'Usage Instruction'};
            app.Image_5 = uiimage(app.WellcomeTab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'misc', 'technical_uni_munich_logo.svg');
            app.Image3_2 = uiimage(app.WellcomeTab);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'misc', 'qrcode_github.com.png');
            app.CheckOurGithubRepoLabel = uilabel(app.WellcomeTab);
            app.CheckOurGithubRepoLabel.Text = 'Check Our Github Repo';
            app.UsageTextArea_2 = uitextarea(app.WellcomeTab);
            app.UsageTextArea_2.Value = {'References:'; '[1] Zhiqing Cao, Xin Sun, and Jiaoying Shi.'; 'Tour into the picture using relative depth calculation.'; 'In Proceedings of the 2004 ACM SIGGRAPH international conference on Virtual Reality continuum and its applications in industry, pages 38–44, 2004.'; '[2] Youichi Horry, Ken‐Ichi Anjyo, and Kiyoshi Arai.'; 'Tour into the picture: using a spidery mesh interface to make animation from a single image.'; 'In Proceedings of the 24th Annual Conference on Computer Graphics and Interactive Techniques, SIGGRAPH ’97, page 225–232, USA,'; '1997. ACM Press/Addison‐Wesley Publishing Co.'; '[3] Yikai Li, Jiayuan Mao, Xiuming Zhang, William T. Freeman, Joshua B. Tenenbaum, Noah Snavely, and Jiajun Wu.'; 'Multi‐plane program induction with 3d box priors.'; 'In Proceedings of the 34th International Conference on Neural Information Processing Systems, NIPS ’20, Red Hook, NY, USA, 2020. Curran Associates Inc.'; '[4] Guihang Wang, Xuejin Chen, and Si Chen.'; 'Cut‐and‐fold: Automatic 3d modeling from a single image.'; 'In 2014 IEEE International Conference on Multimedia and Expo Workshops (ICMEW), pages 1–6, 2014.'};
            app.TextArea = uitextarea(app.WellcomeTab);
            app.TextArea.Value = {'For any issues, Please contact us.'; ''; 'wenbo.ji@tum.de'; ''; 'go68suh@mytum.de'; ''; 'ge52rox@mytum.de'; ''; 'yuming.li@tum.de'; ''; 'shilin1.zhang@tum.de'};
            app.UsageTextArea_3 = uitextarea(app.WellcomeTab);
            app.UsageTextArea_3.Value = {'Step 1: Data Selection:'; '- Spcify the dataset root path.'; '- Choose your favorite image.'; '- Click ''Next Step''.'};
            app.UsageTextArea_4 = uitextarea(app.WellcomeTab);
            app.UsageTextArea_4.Value = {'Step 3: Spidery Mesh Construction:'; '- Specify the vanishing point.'; '- Specify the Inner rectangle.'; '- if the spidery mesh is unfit to perspective. Please adjust vanishing point or Inner rectangle.'; '- Click ''Next Step''.'};
            app.UsageTextArea_5 = uitextarea(app.WellcomeTab);
            app.UsageTextArea_5.Value = {'Step 2: Foreground Selection:'; '- Click ''Label Foreground''.'; '- Draw polygon to bound the object.'; '   - Make sure the polygon is closed'; '- After mask generated, click ''Next Step''.'; '- If there''s no foreground, just click ''Next Step''.'};
            app.UsageTextArea_6 = uitextarea(app.WellcomeTab);
            app.UsageTextArea_6.Value = {'Time to Tour'; '- Foreground object is optional.'; '- Adjust Camera Pose and Angle'; '- Save current view or select a new image.'};
            app.LetsgoButton = uibutton(app.WellcomeTab, 'push');
            app.LetsgoButton.ButtonPushedFcn = createCallbackFcn(app, @LetsgoButtonPushed, true);
            app.LetsgoButton.Text = 'Let''s go!';
            app.Step1DataSelectionTab = uitab(app.TabGroup);
            app.Step1DataSelectionTab.Title = 'Step 1: Data Selection';
            app.UIAxes = uiaxes(app.Step1DataSelectionTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'axes1';
            app.UIAxes.Visible = 'off';
            app.NextStepButton = uibutton(app.Step1DataSelectionTab, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Enable = 'off';
            app.NextStepButton.Visible = 'off';
            app.NextStepButton.Text = 'Next Step';
            app.Tree = uitree(app.Step1DataSelectionTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SpecifyDatasetRootButton = uibutton(app.Step1DataSelectionTab, 'push');
            app.SpecifyDatasetRootButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyDatasetRootButtonPushed, true);
            app.SpecifyDatasetRootButton.Text = 'Specify Dataset Root';
            app.ImageNameTextAreaLabel = uilabel(app.Step1DataSelectionTab);
            app.ImageNameTextAreaLabel.Text = 'Image Name: ';
            app.ImageNameTextArea = uitextarea(app.Step1DataSelectionTab);
            app.ImageNameTextArea.Editable = 'off';
            app.SelectedDataLabel = uilabel(app.Step1DataSelectionTab);
            app.SelectedDataLabel.Text = 'Selected Data:';
            app.ImageSizeTextAreaLabel = uilabel(app.Step1DataSelectionTab);
            app.ImageSizeTextAreaLabel.Text = 'Image Size:';
            app.ImageSizeTextArea = uitextarea(app.Step1DataSelectionTab);
            app.ImageSizeTextArea.Editable = 'off';
            app.Image = uiimage(app.Step1DataSelectionTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'misc', 'technical_uni_munich_logo.svg');
            app.Image2 = uiimage(app.Step1DataSelectionTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'misc', 'Cover.png');
            app.FolderTreeLabel = uilabel(app.Step1DataSelectionTab);
            app.FolderTreeLabel.Text = 'Folder Tree';
            app.Step2ForegroundSelectionTab = uitab(app.TabGroup);
            app.Step2ForegroundSelectionTab.Title = 'Step 2: Foreground Selection';
            app.UIAxes_2 = uiaxes(app.Step2ForegroundSelectionTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Tag = 'axes1';
            app.UIAxesFG = uiaxes(app.Step2ForegroundSelectionTab);
            app.UIAxesFG.XTick = [];
            app.UIAxesFG.YTick = [];
            app.UIAxesFG.Tag = 'axes1';
            app.UIAxesBG = uiaxes(app.Step2ForegroundSelectionTab);
            app.UIAxesBG.XTick = [];
            app.UIAxesBG.YTick = [];
            app.UIAxesBG.Tag = 'axes1';
            app.LabelForegroundButton = uibutton(app.Step2ForegroundSelectionTab, 'push');
            app.LabelForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @LabelForegroundButtonPushed, true);
            app.LabelForegroundButton.Text = 'Label Foreground';
            app.LampForFG = uilamp(app.Step2ForegroundSelectionTab);
            app.NextStepButton_2 = uibutton(app.Step2ForegroundSelectionTab, 'push');
            app.NextStepButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_2Pushed, true);
            app.NextStepButton_2.Text = 'Next Step';
            app.IftheresnoforegroundjustclickNextStepLabel = uilabel(app.Step2ForegroundSelectionTab);
            app.IftheresnoforegroundjustclickNextStepLabel.Text = 'If there''s no foreground, just click ''Next Step''';
            app.SelectanewimageButton = uibutton(app.Step2ForegroundSelectionTab, 'push');
            app.SelectanewimageButton.ButtonPushedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.SelectanewimageButton.Text = 'Select a new image';
            app.Image_2 = uiimage(app.Step2ForegroundSelectionTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'misc', 'technical_uni_munich_logo.svg');
            app.FGResetButton = uibutton(app.Step2ForegroundSelectionTab, 'push');
            app.FGResetButton.ButtonPushedFcn = createCallbackFcn(app, @FGResetButtonPushed, true);
            app.FGResetButton.Text = 'Reset Foreground Mask';
            app.InpaintingBackgroundLabel = uilabel(app.Step2ForegroundSelectionTab);
            app.InpaintingBackgroundLabel.Text = 'Inpainting Background';
            app.ForegroundMaskLabel = uilabel(app.Step2ForegroundSelectionTab);
            app.ForegroundMaskLabel.Text = 'Foreground Mask';
            app.Step3SpideryMeshTab = uitab(app.TabGroup);
            app.Step3SpideryMeshTab.Title = 'Step 3: Spidery Mesh';
            app.UIAxes_3 = uiaxes(app.Step3SpideryMeshTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Tag = 'axes1';
            app.SelectVanishingpointButton = uibutton(app.Step3SpideryMeshTab, 'push');
            app.SelectVanishingpointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVanishingpointButtonPushed, true);
            app.SelectVanishingpointButton.Text = '1: Select Vanishing point';
            app.SelectwallButton = uibutton(app.Step3SpideryMeshTab, 'push');
            app.SelectwallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectwallButtonPushed, true);
            app.SelectwallButton.Text = '2: Select wall';
            app.SelectarectangletoconfirmButton = uibutton(app.Step3SpideryMeshTab, 'state');
            app.SelectarectangletoconfirmButton.Enable = 'off';
            app.SelectarectangletoconfirmButton.Visible = 'off';
            app.SelectarectangletoconfirmButton.Text = 'Select a rectangle to confirm';
            app.LampForVP = uilamp(app.Step3SpideryMeshTab);
            app.LampForRW = uilamp(app.Step3SpideryMeshTab);
            app.ClickthepointtoconfirmButton = uibutton(app.Step3SpideryMeshTab, 'state');
            app.ClickthepointtoconfirmButton.Enable = 'off';
            app.ClickthepointtoconfirmButton.Visible = 'off';
            app.ClickthepointtoconfirmButton.Text = 'Click the point to confirm';
            app.NextStepButton_3 = uibutton(app.Step3SpideryMeshTab, 'push');
            app.NextStepButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_3Pushed, true);
            app.NextStepButton_3.Enable = 'off';
            app.NextStepButton_3.Visible = 'off';
            app.NextStepButton_3.Text = 'Next Step';
            app.SelectanewimageButton_2 = uibutton(app.Step3SpideryMeshTab, 'push');
            app.SelectanewimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.SelectanewimageButton_2.Text = 'Select a new image';
            app.Image_3 = uiimage(app.Step3SpideryMeshTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'misc', 'technical_uni_munich_logo.svg');
            app.TimetoTourTab = uitab(app.TabGroup);
            app.TimetoTourTab.Title = 'Time to Tour';
            app.UIAxes_4 = uiaxes(app.TimetoTourTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.Tag = 'axes1';
            app.ZSliderLabel = uilabel(app.TimetoTourTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.TimetoTourTab);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.MajorTicks = [-5 -3 -1 0 1 3 5];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.thetaSliderLabel = uilabel(app.TimetoTourTab);
            app.thetaSliderLabel.Text = 'theta';
            app.thetaSlider = uislider(app.TimetoTourTab);
            app.thetaSlider.Limits = [-1.5707963267949 1.5707963267949];
            app.thetaSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.phiSliderLabel = uilabel(app.TimetoTourTab);
            app.phiSliderLabel.Text = 'phi';
            app.phiSlider = uislider(app.TimetoTourTab);
            app.phiSlider.Limits = [-1.5707963267949 1.5707963267949];
            app.phiSlider.ValueChangedFcn = createCallbackFcn(app, @phiSliderValueChanged, true);
            app.ResetCameraPoseButton = uibutton(app.TimetoTourTab, 'push');
            app.ResetCameraPoseButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCameraPoseButtonPushed, true);
            app.ResetCameraPoseButton.Text = 'Reset Camera Pose';
            app.YSliderLabel = uilabel(app.TimetoTourTab);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.TimetoTourTab);
            app.YSlider.Limits = [-5 5];
            app.YSlider.MajorTicks = [-5 -3 -1 0 1 3 5];
            app.YSlider.MajorTickLabels = {'-5', '-3', '-1', '0', '1', '3', '5'};
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.XSliderLabel = uilabel(app.TimetoTourTab);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.TimetoTourTab);
            app.XSlider.Limits = [-5 5];
            app.XSlider.MajorTicks = [-5 -3 -1 0 1 3 5];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.ShowForegroundButton = uibutton(app.TimetoTourTab, 'state');
            app.ShowForegroundButton.ValueChangedFcn = createCallbackFcn(app, @ShowForegroundButtonValueChanged, true);
            app.ShowForegroundButton.Enable = 'off';
            app.ShowForegroundButton.Visible = 'off';
            app.ShowForegroundButton.Text = 'Show Foreground';
            app.SelectanewimageButton_3 = uibutton(app.TimetoTourTab, 'push');
            app.SelectanewimageButton_3.ButtonPushedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.SelectanewimageButton_3.Text = 'Select a new image';
            app.Image_4 = uiimage(app.TimetoTourTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'misc', 'technical_uni_munich_logo.svg');
            app.SaveCurrentViewButton = uibutton(app.TimetoTourTab, 'push');
            app.SaveCurrentViewButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentViewButtonPushed, true);
            app.SaveCurrentViewButton.Text = 'Save Current View';
            app.AdjustCameraAngleLabel = uilabel(app.TimetoTourTab);
            app.AdjustCameraAngleLabel.Text = 'Adjust Camera Angle';
            app.AdjustCameraPoseLabel = uilabel(app.TimetoTourTab);
            app.AdjustCameraPoseLabel.Text = 'Adjust Camera Pose';
            app.QuitButton = uibutton(app.TimetoTourTab, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.Tab1_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab1_EnableCheckBox.Text = 'Tab1_Enable';
            app.Tab1_EnableCheckBox.Value = true;
            app.Tab2_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab2_EnableCheckBox.Text = 'Tab2_Enable';
            app.Tab3_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab3_EnableCheckBox.Text = 'Tab3_Enable';
            app.Tab4_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab4_EnableCheckBox.Text = 'Tab4_Enable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end